classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        cartel_entrada  matlab.ui.control.Label
        placa_parking   matlab.ui.control.Label
        date            matlab.ui.control.Label
        estado_barrera  matlab.ui.control.Label
        button          matlab.ui.control.Button
        numCoches       matlab.ui.control.Label
    end

    
    properties (Access = private)
        s % Description
    end  
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfind({'port'},{'COM4'}));
            app.s = serialport("COM4",19200,"Timeout",1.5 );  
            configureTerminator(app.s, "CR/LF", "CR");
            
            global  max numCoches reloj DateVector Date
            
            max = 200;
            numCoches = 0;
            
            reloj = clock;
            DateVector = [reloj(1),reloj(2),reloj(3),reloj(4),reloj(5),reloj(6)];
            Date = datestr(DateVector);
            
            app.estado_barrera.Text = "BARRERA CERRADA";
            app.date.Text = Date;
            app.estado_barrera.Text = num2str(max-numCoches);


        end

        % Button pushed function: button
        function buttonButtonPushed(app, event)
            global max numCoches reloj DateVector Date
            
            reloj = clock;
            DateVector = [reloj(1),reloj(2),reloj(3),reloj(4),reloj(5),reloj(6)];
            Date = datestr(DateVector);
            
            flush(app.s,"input");
            writeline(app.s,"0");
            
            if(num2str(readline(app.s)) == "1")      
                %Al recibir '1' se indica que si se detecta un coche en el
                %sensor
                
                app.estado_barrera.Text = "Solcitud Pendiente ...";
                pause(1);
                writeline(app.s, "1");  
                    %Solicitud a Arduino para que le indique si hay sitio o no
                    %en el parking
                if(num2str(readline(app.s)) == "2")
                    numCoches = numCoches + 1;
                    app.estado_barrera.Text = "Solictud Aceptada";
                    app.numCoches.Text = num2str(max-numCoches);
                else
                    app.estado_barrera.Text = "Solicitud Denegada";
                end                
            else
                 app.estado_barrera.Text = "Solicitud Denegada"  
            end
            
            app.date.Text = Date;
            pause(1);
            app.estado_barrera.Text = "BARRERA CERRADA";
            app.date.Text = Date;
            
            flush(app.s,"input");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.cartel_entrada = uilabel(app.UIFigure);
            app.cartel_entrada.Text = {'ENTRADA PARKING'; 'UNIVERSIDAD'};
            app.placa_parking = uilabel(app.UIFigure);
            app.placa_parking.Text = 'P';
            app.date = uilabel(app.UIFigure);
            app.estado_barrera = uilabel(app.UIFigure);
            app.button = uibutton(app.UIFigure, 'push');
            app.button.ButtonPushedFcn = createCallbackFcn(app, @buttonButtonPushed, true);
            app.button.Text = '';
            app.numCoches = uilabel(app.UIFigure);
            app.numCoches.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end